classdef Fase1MYKI_APP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DisconnettiCyberGloveButton     matlab.ui.control.Button
        ConnettiCybergloveButton        matlab.ui.control.Button
        TactorsPanel                    matlab.ui.container.Panel
        DuratamsKnob                    matlab.ui.control.Knob
        DuratamsKnobLabel               matlab.ui.control.Label
        VibraButton                     matlab.ui.control.Button
        GainKnob                        matlab.ui.control.Knob
        GainKnobLabel                   matlab.ui.control.Label
        FreqHzKnob                      matlab.ui.control.Knob
        FreqHzKnobLabel                 matlab.ui.control.Label
        SelTactorButtonGroup            matlab.ui.container.ButtonGroup
        Vib3Button                      matlab.ui.control.RadioButton
        Vib2Button                      matlab.ui.control.RadioButton
        Vib1Button                      matlab.ui.control.RadioButton
        PortaListBox                    matlab.ui.control.ListBox
        CercaPorteCyberGloveButton      matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        InizializzaTactorsButton        matlab.ui.control.Button
        CyberGloveCalibrationDataPanel  matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        PosizListBox                    matlab.ui.control.ListBox
        PosizListBoxLabel               matlab.ui.control.Label
        SalvaPosizioneButton            matlab.ui.control.Button
        InterrogaCyberGloveButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        oggi;
        percorso;
        glove;
        tab;
        ti;
        porta_com;
        DataTags;
    end
    
    methods (Access = private)

        function uccidiGlove(app)
            try
                fclose(app.glove.serial_p);
                delete(app.glove.serial_p);
                clear app.glove
            catch
                disp("no gloves");
            end

            try
                clear app.ti
            catch
                disp("no tactors");
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.oggi = string(datetime('now','Format','dd-MM-yy'));
            app.percorso = strcat('Fase1_',app.oggi);
            
            if ~exist(app.percorso, 'dir')
                mkdir(app.percorso);
                addpath(app.percorso);
            end

            serials = serialportlist;
            app.PortaListBox.Items = serials;
            app.porta_com = app.PortaListBox.Value;

            if ~exist("DataTags","var")
                if exist("DataTags.mat", "file") ~= 0
                    load DataTags.mat DataTags
                end
            end

            app.DataTags = DataTags; %#ok<ADPROP>         
        end

        % Button pushed function: InizializzaTactorsButton
        function InizializzaTactorsButtonPushed(app, event)
            app.ti = TactorInterfaceML;

            numFound = app.ti.Discover;

            if (numFound > 0)
                for devIndex = 0:(numFound-1)
                    [dev, type] = app.ti.GetDevice(devIndex);
                    if dev == "\\.\COM13"
                        app.ti.Connect(dev, type);
                    end
                end
            end

            app.TextArea.Value = strcat("Connesso, ", dev, " e ", type);
        end

        % Button pushed function: CercaPorteCyberGloveButton
        function CercaPorteCyberGloveButtonPushed(app, event)
            serials = serialportlist;
            app.PortaListBox.Items = serials;
        end

        % Button pushed function: InterrogaCyberGloveButton
        function InterrogaCyberGloveButtonPushed(app, event)
            write(app.glove.serial_p, 'G', 'uint8');
            data = read(app.glove.serial_p, 20, 'uint8');

            if data(20) == 0
                if isempty(app.tab)
                    app.tab = data;
                else
                    app.tab = [app.tab; data];
                end
            end
            
            temp_tab = array2table(app.tab);
            app.UITable.Data = temp_tab;
            app.UITable.ColumnWidth = num2cell(35*ones(1,20));

        end

        % Button pushed function: SalvaPosizioneButton
        function SalvaPosizioneButtonPushed(app, event)
            if ~isa(app.tab, "table")
                app.tab = array2table(app.tab);
            end
            app.tab.Properties.VariableNames(1:20) = app.DataTags;

            writetable(app.tab,strcat(app.percorso,"\","cal_", app.PosizListBox.Value,"_",app.oggi,".xlsx"));
        end

        % Button pushed function: VibraButton
        function VibraButtonPushed(app, event)
            freq = 10 * app.FreqHzKnob.Value;
            gain = app.GainKnob.Value;
            vib_index = [app.Vib1Button.Value app.Vib2Button.Value app.Vib3Button.Value];
            pulse_delta = app.DuratamsKnob.Value;
            delay = pulse_delta / 10 - 1;

            if (app.ti.deviceConnected == true && vib_index(1) == 1)
                app.ti.ChangeFreq(devIndex, 1, freq, 0);
                app.ti.ChangeGain(devIndex, 1, gain, 0);
                app.ti.Pulse(devIndex,1, pulse_delta, 0);
                app.ti.Pulse(devIndex, 1, pulse_delta, delay-2);
            end
            if (app.ti.deviceConnected == true && vib_index(2) == 1)
                app.ti.ChangeFreq(devIndex, 2, freq, 0);
                app.ti.ChangeGain(devIndex, 2, gain, 0);
                app.ti.Pulse(devIndex, 2, pulse_delta, 1);
                app.ti.Pulse(devIndex, 2, pulse_delta, delay-1);
            end
            if (app.ti.deviceConnected == true && vib_index(3) == 1)
                app.ti.ChangeFreq(devIndex, 3, freq, 0);
                app.ti.ChangeGain(devIndex, 3, gain, 0);
                app.ti.Pulse(devIndex, 3, pulse_delta, 2);
                app.ti.Pulse(devIndex, 3, pulse_delta, delay);
            end
        end

        % Button pushed function: ConnettiCybergloveButton
        function ConnettiCybergloveButtonPushed(app, event)
            try
                app.porta_com = app.PortaListBox.Value;
                app.glove = Cyberglove(app.porta_com);
            catch
                disp("anort")
                delete(app)
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            uccidiGlove(app)
            delete(app)            
        end

        % Button pushed function: DisconnettiCyberGloveButton
        function DisconnettiCyberGloveButtonPushed(app, event)
            uccidiGlove(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CyberGloveCalibrationDataPanel = uipanel(app.UIFigure);
            app.CyberGloveCalibrationDataPanel.Title = 'CyberGlove Calibration Data';
            app.InterrogaCyberGloveButton = uibutton(app.CyberGloveCalibrationDataPanel, 'push');
            app.InterrogaCyberGloveButton.ButtonPushedFcn = createCallbackFcn(app, @InterrogaCyberGloveButtonPushed, true);
            app.InterrogaCyberGloveButton.Text = {'Interroga'; 'CyberGlove'};
            app.SalvaPosizioneButton = uibutton(app.CyberGloveCalibrationDataPanel, 'push');
            app.SalvaPosizioneButton.ButtonPushedFcn = createCallbackFcn(app, @SalvaPosizioneButtonPushed, true);
            app.SalvaPosizioneButton.Text = {'Salva'; 'Posizione'};
            app.PosizListBoxLabel = uilabel(app.CyberGloveCalibrationDataPanel);
            app.PosizListBoxLabel.Text = 'Posiz.';
            app.PosizListBox = uilistbox(app.CyberGloveCalibrationDataPanel);
            app.PosizListBox.Items = {'Flex', 'Ext'};
            app.PosizListBox.Value = 'Flex';
            app.UITable = uitable(app.CyberGloveCalibrationDataPanel);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.InizializzaTactorsButton = uibutton(app.UIFigure, 'push');
            app.InizializzaTactorsButton.ButtonPushedFcn = createCallbackFcn(app, @InizializzaTactorsButtonPushed, true);
            app.InizializzaTactorsButton.Text = {'Inizializza'; 'Tactors'};
            app.TextArea = uitextarea(app.UIFigure);
            app.CercaPorteCyberGloveButton = uibutton(app.UIFigure, 'push');
            app.CercaPorteCyberGloveButton.ButtonPushedFcn = createCallbackFcn(app, @CercaPorteCyberGloveButtonPushed, true);
            app.CercaPorteCyberGloveButton.Text = {'Cerca Porte'; 'CyberGlove'};
            app.PortaListBox = uilistbox(app.UIFigure);
            app.PortaListBox.Items = {};
            app.PortaListBox.Value = {};
            app.TactorsPanel = uipanel(app.UIFigure);
            app.TactorsPanel.Title = 'Tactors';
            app.SelTactorButtonGroup = uibuttongroup(app.TactorsPanel);
            app.SelTactorButtonGroup.Title = 'Sel. Tactor';
            app.Vib1Button = uiradiobutton(app.SelTactorButtonGroup);
            app.Vib1Button.Text = 'Vib 1';
            app.Vib1Button.Value = true;
            app.Vib2Button = uiradiobutton(app.SelTactorButtonGroup);
            app.Vib2Button.Text = 'Vib 2';
            app.Vib3Button = uiradiobutton(app.SelTactorButtonGroup);
            app.Vib3Button.Text = 'Vib 3';
            app.FreqHzKnobLabel = uilabel(app.TactorsPanel);
            app.FreqHzKnobLabel.Text = 'Freq (Hz)';
            app.FreqHzKnob = uiknob(app.TactorsPanel, 'continuous');
            app.FreqHzKnob.Limits = [60 160];
            app.FreqHzKnob.Value = 60;
            app.GainKnobLabel = uilabel(app.TactorsPanel);
            app.GainKnobLabel.Text = 'Gain';
            app.GainKnob = uiknob(app.TactorsPanel, 'continuous');
            app.GainKnob.Limits = [50 255];
            app.GainKnob.Value = 50;
            app.VibraButton = uibutton(app.TactorsPanel, 'push');
            app.VibraButton.ButtonPushedFcn = createCallbackFcn(app, @VibraButtonPushed, true);
            app.VibraButton.Text = 'Vibra';
            app.DuratamsKnobLabel = uilabel(app.TactorsPanel);
            app.DuratamsKnobLabel.Text = 'Durata (ms)';
            app.DuratamsKnob = uiknob(app.TactorsPanel, 'continuous');
            app.DuratamsKnob.Limits = [100 5000];
            app.DuratamsKnob.Value = 100;
            app.ConnettiCybergloveButton = uibutton(app.UIFigure, 'push');
            app.ConnettiCybergloveButton.ButtonPushedFcn = createCallbackFcn(app, @ConnettiCybergloveButtonPushed, true);
            app.ConnettiCybergloveButton.Text = {'Connetti'; 'Cyberglove'};
            app.DisconnettiCyberGloveButton = uibutton(app.UIFigure, 'push');
            app.DisconnettiCyberGloveButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnettiCyberGloveButtonPushed, true);
            app.DisconnettiCyberGloveButton.Text = {'Disconnetti'; 'CyberGlove'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fase1MYKI_APP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end